classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FossilUI         matlab.ui.Figure
        FileMenu         matlab.ui.container.Menu
        AboutMenu        matlab.ui.container.Menu
        ProgramInfoMenu  matlab.ui.container.Menu
        EditMenu         matlab.ui.container.Menu
        EditorPane       matlab.ui.control.TextArea
        HTMLPane         matlab.ui.control.HTML
        RUNButton        matlab.ui.control.Button
    end

    
    properties (Access = public)
        ver = '1.0'; % current version of the app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd ~/Documents/GitHub/Fossil
            %% ----- UI positions info - see txt document
            %  [x: 
            %   y: 
            %   width: 
            %   height: ]
            
            % set Fossil UI size to screen size position
            screen_size = get(groot, "ScreenSize"); % [x, y, width, height]
            app.FossilUI.Position = screen_size;
            
            % change RUN button position to above Editor Pane, top left
            app.RUNButton.Position = [10, screen_size(4)-155, 80, 30];
            
            % edit positions for Editor & HTML panes, depending on screen
            % sizes.
            app.EditorPane.Position = [10, 20, ...
                screen_size(3)/2-50, screen_size(4)-180];
            app.HTMLPane.Position = [screen_size(3)/2+10, 20, ...
                screen_size(3)/2-50, screen_size(4)-180];
            %%
            
            % set Fossil UI name to "Fossil v[version] - [/dir]"
            app.FossilUI.Name = ['Fossil v' app.ver ' - ' pwd];
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            % open and write to f90 file
            fileID = fopen('temp/prog.f90','w');
            fprintf(fileID,'%s\n',app.EditorPane.Value{:});
            fclose(fileID);
            %app.HTMLPane.HTMLSource = app.EditorPane.Value{:};
            
            % Run compile command in terminal, printing all good and bad 
            % outputs, flag is currently unused
            [~, cmd] = system("cd ~/Documents/GitHub/Fossil/temp; gfortran -o f prog.f90; ./f");
            app.HTMLPane.HTMLSource = cmd;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FossilUI = uifigure('Visible', 'off');
            app.FossilUI.Name = 'Fossil 0.9';
            app.FileMenu = uimenu(app.FossilUI);
            app.FileMenu.Text = 'File';
            app.AboutMenu = uimenu(app.FileMenu);
            app.AboutMenu.Text = 'About';
            app.ProgramInfoMenu = uimenu(app.FileMenu);
            app.ProgramInfoMenu.Text = 'Program Info';
            app.EditMenu = uimenu(app.FossilUI);
            app.EditMenu.Text = 'Edit';
            app.EditorPane = uitextarea(app.FossilUI);
            app.HTMLPane = uihtml(app.FossilUI);
            app.RUNButton = uibutton(app.FossilUI, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.FossilUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FossilUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FossilUI)
        end
    end
end